classdef code_correcteur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CheckconnectionButton      matlab.ui.control.Button
        DatabeforeEditFieldLabel   matlab.ui.control.Label
        DatabeforeEditField        matlab.ui.control.EditField
        DatacodedEditFieldLabel    matlab.ui.control.Label
        DatacodedEditField         matlab.ui.control.EditField
        DatasendEditFieldLabel     matlab.ui.control.Label
        DatasendEditField          matlab.ui.control.EditField
        DatareceiveEditFieldLabel  matlab.ui.control.Label
        DatareceiveEditField       matlab.ui.control.EditField
        DataencodedEditFieldLabel  matlab.ui.control.Label
        DataencodedEditField       matlab.ui.control.EditField
        DataendEditFieldLabel      matlab.ui.control.Label
        DataendEditField           matlab.ui.control.EditField
        DoexchangeButton           matlab.ui.control.Button
        CorrectorcodeButtonGroup   matlab.ui.container.ButtonGroup
        CRCButton                  matlab.ui.control.RadioButton
        codelinaireButton          matlab.ui.control.RadioButton
        codepolynomialeButton      matlab.ui.control.RadioButton
        ConfigurationsLabel        matlab.ui.control.Label
        ExchangeLabel              matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        SerialportListBoxLabel     matlab.ui.control.Label
        SerialportListBox          matlab.ui.control.ListBox
        RefreshlistSwitchLabel     matlab.ui.control.Label
        RefreshlistSwitch          matlab.ui.control.ToggleSwitch
        CloseconnectionButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        serial_com = 0; % for serial communication
        selected_port = 0;
        textEdit_data ="";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            all_ports = seriallist;
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
            set(app.DoexchangeButton, "enable", "off");
            set(app.CloseconnectionButton, "Visible", "off");
        end

        % Button pushed function: CheckconnectionButton
        function CheckconnectionButtonPushed(app, event)
            try
                app.serial_com = serial(app.selected_port);
                fopen(app.serial_com);
                connect_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.DoexchangeButton, "enable", "on");
                set(app.CheckconnectionButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "on");
            catch ME
                connect_test_response = "Can't connect, try instrfind command to see if port already used";
            end    
              
            app.textEdit_data = [app.textEdit_data, connect_test_response];
            set(app.TextArea,"Value",app.textEdit_data);
        end

        % Value changed function: SerialportListBox
        function SerialportListBoxValueChanged(app, event)
            app.selected_port = get(app.SerialportListBox,"Value");
            app.textEdit_data = [app.textEdit_data, app.selected_port];
            set(app.TextArea,"Value",app.textEdit_data);
        end

        % Button pushed function: DoexchangeButton
        function DoexchangeButtonPushed(app, event)
            % Call external script fonction of the corretorCode Selection
            % chosen
            code_correcteur_selectionne = get(get(app.CorrectorcodeButtonGroup, "SelectedObject"),"Text");
            app.textEdit_data = [app.textEdit_data, "code selected : ", code_correcteur_selectionne];
            set(app.TextArea,"Value",app.textEdit_data);
            
            data_to_code = get(app.DatabeforeEditField,"Value");
            if (data_to_code == "")
                app.textEdit_data = [app.textEdit_data, "please enter data noob\r\n"];
                set(app.TextArea,"Value",app.textEdit_data);
            end
            
            if (code_correcteur_selectionne == "CRC")
                data_encoded = crc_function(data_to_code,1);
            elseif (code_correcteur_selectionne == "code linéaire")
                data_encoded = lineaire_function();
            elseif (code_correcteur_selectionne == "code polynomiale")
                data_encoded = polynomiale_function();
            end
            
            % Set encoded data after coding
            set(app.DatacodedEditField,"Value",data_encoded);
            
            % Go to binary to change some bits
            x= reshape(dec2bin(data_encoded, 8)', 1, [])  - '0';
            % Then Add noise
            x(4) = ~x(4);
            
            % reconvert to char
            b='';
            for j = (0:length(x)/8-1)
                b = [b,sum(x(1+(j*8):8+(j*8)).*2.^[7:-1:0])];
            end
            
            % Send to USB cable
            fwrite(app.serial_com,b);
            set(app.DatasendEditField,"Value",b);
            
            % Read data receive
            [data_to_decode_vector, nb_charac] = fread(app.serial_com);
            data_to_decode_ascii = num2str(data_to_decode_vector.');
            data_to_decode_str = char(data_to_decode_ascii);
            set(app.DatareceiveEditField,"Value",data_to_decode_str);
            
            % decode received datas
            decData = crc_function(data_to_decode_ascii,0);
            set(app.DataencodedEditField,"Value",decData);
            
            % check error and BER
            % numerr = biterr(data_to_decode_string,decData);
            % set(app.DataendEditField,"Value",numerr);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                CloseconnectionButtonPushed(app, event);
                % fclose(app.serial_com);
            catch ME
                CloseconnectionButtonPushed(app, event)
                % fclose(app.serial_com);
            end
            delete(app);
        end

        % Value changed function: RefreshlistSwitch
        function RefreshlistSwitchValueChanged(app, event)
            value = app.RefreshlistSwitch.Value;
            all_ports = seriallist;
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
        end

        % Button pushed function: CloseconnectionButton
        function CloseconnectionButtonPushed(app, event)
            try 
                fclose(app.serial_com);
                
                set(app.DoexchangeButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "off");
                close_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.CheckconnectionButton, "enable", "on");
            catch ME
                close_test_response = ["Can't close port ! "];
            end
            app.textEdit_data = [app.textEdit_data, close_test_response];
            set(app.TextArea,"Value",app.textEdit_data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CheckconnectionButton = uibutton(app.UIFigure, 'push');
            app.CheckconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckconnectionButtonPushed, true);
            app.CheckconnectionButton.Text = 'Check connection';
            app.DatabeforeEditFieldLabel = uilabel(app.UIFigure);
            app.DatabeforeEditFieldLabel.Text = 'Data before';
            app.DatabeforeEditField = uieditfield(app.UIFigure, 'text');
            app.DatacodedEditFieldLabel = uilabel(app.UIFigure);
            app.DatacodedEditFieldLabel.Text = 'Data coded';
            app.DatacodedEditField = uieditfield(app.UIFigure, 'text');
            app.DatasendEditFieldLabel = uilabel(app.UIFigure);
            app.DatasendEditFieldLabel.Text = 'Data send';
            app.DatasendEditField = uieditfield(app.UIFigure, 'text');
            app.DatareceiveEditFieldLabel = uilabel(app.UIFigure);
            app.DatareceiveEditFieldLabel.Text = 'Data receive';
            app.DatareceiveEditField = uieditfield(app.UIFigure, 'text');
            app.DataencodedEditFieldLabel = uilabel(app.UIFigure);
            app.DataencodedEditFieldLabel.Text = 'Data encoded';
            app.DataencodedEditField = uieditfield(app.UIFigure, 'text');
            app.DataendEditFieldLabel = uilabel(app.UIFigure);
            app.DataendEditFieldLabel.Text = 'Data end';
            app.DataendEditField = uieditfield(app.UIFigure, 'text');
            app.DoexchangeButton = uibutton(app.UIFigure, 'push');
            app.DoexchangeButton.ButtonPushedFcn = createCallbackFcn(app, @DoexchangeButtonPushed, true);
            app.DoexchangeButton.Text = 'Do exchange';
            app.CorrectorcodeButtonGroup = uibuttongroup(app.UIFigure);
            app.CorrectorcodeButtonGroup.Title = 'Corrector code';
            app.CRCButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.CRCButton.Text = 'CRC';
            app.CRCButton.Value = true;
            app.codelinaireButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.codelinaireButton.Text = 'code linéaire';
            app.codepolynomialeButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.codepolynomialeButton.Text = 'code polynomiale';
            app.ConfigurationsLabel = uilabel(app.UIFigure);
            app.ConfigurationsLabel.Text = 'Configurations';
            app.ExchangeLabel = uilabel(app.UIFigure);
            app.ExchangeLabel.Text = 'Exchange';
            app.TextArea = uitextarea(app.UIFigure);
            app.SerialportListBoxLabel = uilabel(app.UIFigure);
            app.SerialportListBoxLabel.Text = 'Serial port';
            app.SerialportListBox = uilistbox(app.UIFigure);
            app.SerialportListBox.ValueChangedFcn = createCallbackFcn(app, @SerialportListBoxValueChanged, true);
            app.RefreshlistSwitchLabel = uilabel(app.UIFigure);
            app.RefreshlistSwitchLabel.Text = 'Refresh list';
            app.RefreshlistSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RefreshlistSwitch.ValueChangedFcn = createCallbackFcn(app, @RefreshlistSwitchValueChanged, true);
            app.CloseconnectionButton = uibutton(app.UIFigure, 'push');
            app.CloseconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CloseconnectionButtonPushed, true);
            app.CloseconnectionButton.Text = 'Close connection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code_correcteur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end